classdef GmidHelper < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        byRixianSEULabel                matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        ClearButton                     matlab.ui.control.Button
        PCHSizePanel                    matlab.ui.container.Panel
        CalculateButton_6               matlab.ui.control.Button
        ParametersTextArea_2            matlab.ui.control.TextArea
        ParametersTextArea_2Label       matlab.ui.control.Label
        SALabel_4                       matlab.ui.control.Label
        gmIdEditField_2                 matlab.ui.control.NumericEditField
        gmIdEditField_2Label            matlab.ui.control.Label
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        gmButton_2                      matlab.ui.control.RadioButton
        IDButton_2                      matlab.ui.control.RadioButton
        IDuAorgmuSEditField_2           matlab.ui.control.NumericEditField
        IDuAorgmuSEditField_2Label      matlab.ui.control.Label
        NCHSizePanel                    matlab.ui.container.Panel
        ParametersTextArea              matlab.ui.control.TextArea
        ParametersTextAreaLabel         matlab.ui.control.Label
        CalculateButton_5               matlab.ui.control.Button
        SALabel_3                       matlab.ui.control.Label
        gmIdEditField                   matlab.ui.control.NumericEditField
        gmIdEditFieldLabel              matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        gmButton                        matlab.ui.control.RadioButton
        IDButton                        matlab.ui.control.RadioButton
        IDuAorgmuSEditField             matlab.ui.control.NumericEditField
        IDuAorgmuSEditFieldLabel        matlab.ui.control.Label
        GmidHelperLabel                 matlab.ui.control.Label
        PCHPanel                        matlab.ui.container.Panel
        Label_11                        matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        FromtoEditField_6               matlab.ui.control.NumericEditField
        FromtoEditField_5               matlab.ui.control.NumericEditField
        FromtoEditField_4               matlab.ui.control.NumericEditField
        PlotButton_2                    matlab.ui.control.Button
        DropDown_10                     matlab.ui.control.DropDown
        CalculateButton_4               matlab.ui.control.Button
        CalculateButton_3               matlab.ui.control.Button
        loadButton_2                    matlab.ui.control.Button
        DropDown_14                     matlab.ui.control.DropDown
        CurvesUnderDifferentLVDSVSBLabel_2  matlab.ui.control.Label
        OtherParametersTextArea_4       matlab.ui.control.TextArea
        OtherParametersTextArea_4Label  matlab.ui.control.Label
        VGSEditField_2                  matlab.ui.control.NumericEditField
        VGSEditField_2Label             matlab.ui.control.Label
        VLabel_6                        matlab.ui.control.Label
        OtherParametersTextArea_3       matlab.ui.control.TextArea
        OtherParametersTextArea_3Label  matlab.ui.control.Label
        SALabel_2                       matlab.ui.control.Label
        gmIDEditField_2                 matlab.ui.control.NumericEditField
        gmIDEditField_2Label            matlab.ui.control.Label
        VLabel_5                        matlab.ui.control.Label
        LEditField_6                    matlab.ui.control.NumericEditField
        LEditFieldLabel_5               matlab.ui.control.Label
        VLabel_4                        matlab.ui.control.Label
        LEditField_5                    matlab.ui.control.NumericEditField
        LEditFieldLabel_4               matlab.ui.control.Label
        umLabel_2                       matlab.ui.control.Label
        LEditField_4                    matlab.ui.control.NumericEditField
        LEditField_4Label               matlab.ui.control.Label
        FilenameEditField_2             matlab.ui.control.EditField
        FilenameLabel_2                 matlab.ui.control.Label
        NCHPanel                        matlab.ui.container.Panel
        Label_9                         matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        FromtoEditField_3               matlab.ui.control.NumericEditField
        FromtoEditField_2               matlab.ui.control.NumericEditField
        FromtoEditField                 matlab.ui.control.NumericEditField
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        DropDown_13                     matlab.ui.control.DropDown
        DropDown                        matlab.ui.control.DropDown
        PlotButton                      matlab.ui.control.Button
        CurvesUnderDifferentLVDSVSBLabel  matlab.ui.control.Label
        OtherParametersTextArea_2       matlab.ui.control.TextArea
        OtherParametersTextArea_2Label  matlab.ui.control.Label
        CalculateButton_2               matlab.ui.control.Button
        VGSEditField                    matlab.ui.control.NumericEditField
        VGSEditFieldLabel               matlab.ui.control.Label
        VLabel_3                        matlab.ui.control.Label
        OtherParametersTextArea         matlab.ui.control.TextArea
        OtherParametersTextAreaLabel    matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        SALabel                         matlab.ui.control.Label
        gmIDEditField                   matlab.ui.control.NumericEditField
        gmIDEditFieldLabel              matlab.ui.control.Label
        VLabel_2                        matlab.ui.control.Label
        LEditField_3                    matlab.ui.control.NumericEditField
        LEditFieldLabel_3               matlab.ui.control.Label
        VLabel                          matlab.ui.control.Label
        LEditField_2                    matlab.ui.control.NumericEditField
        LEditFieldLabel_2               matlab.ui.control.Label
        umLabel                         matlab.ui.control.Label
        LEditField                      matlab.ui.control.NumericEditField
        LEditFieldLabel                 matlab.ui.control.Label
        loadButton                      matlab.ui.control.Button
        FilenameEditField               matlab.ui.control.EditField
        FilenameLabel                   matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loadButton
        function loadButtonPushed(app, event)
            global nch
            [file_nch,path_nch] = uigetfile('*.mat');
            app.FilenameEditField.Value = file_nch;
            load([path_nch file_nch]);  %加载数据
            msgbox("数据加载完毕","通知")
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            global  nch   % nch 就是加载完的结构体  ex: nch.TEMP
            % 变量导入
            L = app.LEditField.Value;
            VSB = app.LEditField_2.Value;
            VDS = app.LEditField_3.Value;
            gm_ID = app.gmIDEditField.Value;
            %% 查询过程
            fT=look_up(nch,'GM_CGG','GM_ID',gm_ID,'L',L,'VSB',VSB,'VDS',VDS)/(2*pi);
            VGS=look_upVGS(nch,'GM_ID',gm_ID,'L',L,'VSB',VSB,'VDS',VDS);
            VT=look_up(nch,'VT','VGS',VGS,'L',L,'VSB',VSB,'VDS',VDS);
            gmro=look_up(nch,'GM_GDS','GM_ID',gm_ID,'L',L,'VSB',VSB,'VDS',VDS);
            ID_W=look_up(nch,'ID_W','GM_ID',gm_ID,'L',L,'VSB',VSB,'VDS',VDS);
            CGD_CGG=look_up(nch,'CGD_CGG','GM_ID',gm_ID,'L',L,'VSB',VSB,'VDS',VDS);
            CGS_CGG=look_up(nch,'CGS_CGG','GM_ID',gm_ID,'L',L,'VSB',VSB,'VDS',VDS);


            %% 查询结果显示
            app.OtherParametersTextArea.Value = [strcat("VGS=",num2str(VGS,4),"V") ...
                strcat("VGS-VTH=",num2str(VGS-VT,4),"V") ...
                strcat("fT=",num2str(fT/1e9,4),"GHz") ...
                strcat("gm*ro=",num2str(gmro,4)) ...
                strcat("ID/W=",num2str(ID_W*1e6,4),"uA/um") ...
                strcat("CGD/CGG=",num2str(CGD_CGG,4)) ...
                strcat("CGS/CGG=",num2str(CGS_CGG,4))]; % 这里给字符串结果
            
            
        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)
            global  nch
            % 变量导入
            L = app.LEditField.Value;
            VSB = app.LEditField_2.Value;
            VDS = app.LEditField_3.Value;
            VGS = app.VGSEditField.Value;
            
            %% 查询过程
            fT=look_up(nch,'GM_CGG','VGS',VGS,'L',L,'VSB',VSB,'VDS',VDS)/(2*pi);
            VT=look_up(nch,'VT','VGS',VGS,'L',L,'VSB',VSB,'VDS',VDS);
            gm_ID=look_up(nch,'GM_ID','VGS',VGS,'L',L,'VSB',VSB,'VDS',VDS);
            gmro=look_up(nch,'GM_GDS','VGS',VGS,'L',L,'VSB',VSB,'VDS',VDS);
            ID_W=look_up(nch,'ID_W','VGS',VGS,'L',L,'VSB',VSB,'VDS',VDS);
            CGD_CGG=look_up(nch,'CGD_CGG','VGS',VGS,'L',L,'VSB',VSB,'VDS',VDS);
            CGS_CGG=look_up(nch,'CGS_CGG','VGS',VGS,'L',L,'VSB',VSB,'VDS',VDS);            


            %% 查询结果显示
            app.OtherParametersTextArea_2.Value = [strcat("gm/ID=",num2str(gm_ID,4),"S/A") ...
                strcat("VGS-VTH=",num2str(VGS-VT,4),"V") ...
                strcat("fT=",num2str(fT/1e9,4),"GHz") ...
                strcat("gm*ro=",num2str(gmro,4)) ...
                strcat("ID/W=",num2str(ID_W*1e6,4),"uA/um") ...
                strcat("CGD/CGG=",num2str(CGD_CGG,4)) ...
                strcat("CGS/CGG=",num2str(CGS_CGG,4))]; % 这里给字符串结果 
            
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            %% 绘图 Curves Over Different L/VSB/VDS
                % gm/Id-VGS
                % Id/W-gm/Id
                % gm/gds-gm/Id
                % ft-gm/Id
                % Cgd/Cgg-gm/Id
                % Cdd/Cgg-gm/Id        
            global nch
            
            if strcmp(app.DropDown_13.Value,'L')   % 绘图 L
                L=app.FromtoEditField.Value:app.FromtoEditField_2.Value:app.FromtoEditField_3.Value;
                VSB = app.LEditField_2.Value;
                VDS = app.LEditField_3.Value;
                for i=1:length(L)
                    LL{i}=['L=',num2str(L(i)),'um'];
                end
                switch(app.DropDown.Value)  %% 判断绘图类型 
                    case 'gm/Id-VGS'
                        VGS=0:0.05:1.2;
                        gm_ID=look_up(nch,'GM_ID','VGS',VGS,'VDS',VDS,'VSB',VSB,'L',L);
                        plot(VGS,gm_ID)
                        xlabel("VGS(V)"); ylabel("gm/Id(S/A)")
                    case 'Id/W-gm/Id'
                        gm_ID=5:0.5:30;
                        ID_W=look_up(nch,'ID_W','GM_ID',gm_ID,'VDS',VDS,'VSB',VSB,'L',L);
                        plot(gm_ID,ID_W*1e6)
                        xlabel("gm/Id(S/A)"); ylabel("Id/W(uA/um)")
                    case 'gm/gds-gm/Id'
                        gm_ID=5:0.5:30;
                        gm_gds=look_up(nch,'GM_GDS','GM_ID',gm_ID,'VDS',VDS,'VSB',VSB,'L',L);
                        plot(gm_ID,20*log10(gm_gds)) 
                        xlabel("gm/Id(S/A)"); ylabel("gm/gds(dB)")
                    case 'ft-gm/Id'
                        gm_ID=5:0.5:30;
                        gm_cgg=look_up(nch,'GM_CGG','GM_ID',gm_ID,'VDS',VDS,'VSB',VSB,'L',L);
                        plot(gm_ID,gm_cgg*1e-9)  
                        xlabel("gm/Id(S/A)"); ylabel("fT(GHz)")
                    case 'Cgd/Cgg-gm/Id'
                        gm_ID=5:0.5:30;
                        cgd_cgg=look_up(nch,'CGD_CGG','GM_ID',gm_ID,'VDS',VDS,'VSB',VSB,'L',L);
                        plot(gm_ID,cgd_cgg)   
                        xlabel("gm/Id(S/A)"); ylabel("Cgd/Cgg")
                    case 'Cdd/Cgg-gm/Id'
                        gm_ID=5:0.5:30;
                        cgs_cgg=look_up(nch,'CGS_CGG','GM_ID',gm_ID,'VDS',VDS,'VSB',VSB,'L',L);
                        plot(gm_ID,cgs_cgg)   
                        xlabel("gm/Id(S/A)"); ylabel("Cgs/Cgg")
                end
                legend(LL,'Location','Best')
                grid on
            elseif strcmp(app.DropDown_13.Value,'VDS')% 绘图 VDS
                VDS=app.FromtoEditField.Value:app.FromtoEditField_2.Value:app.FromtoEditField_3.Value;
                L = app.LEditField.Value;
                VSB = app.LEditField_2.Value;  
                for i=1:length(VDS)
                    LL{i}=['VDS=',num2str(VDS(i)),'V'];
                end                
                switch(app.DropDown.Value)  %% 判断绘图类型 
                    case 'gm/Id-VGS'
                        VGS=0:0.05:1.2;
                        gm_ID=look_up(nch,'GM_ID','VGS',VGS,'VDS',VDS,'VSB',VSB,'L',L);
                        plot(VGS,gm_ID)
                        xlabel("VGS(V)"); ylabel("gm/Id(S/A)")
                    case 'Id/W-gm/Id'
                        gm_ID=5:0.5:30;
                        ID_W=look_up(nch,'ID_W','GM_ID',gm_ID,'VDS',VDS,'VSB',VSB,'L',L);
                        plot(gm_ID,ID_W*1e6)
                        xlabel("gm/Id(S/A)"); ylabel("Id/W(uA/um)")
                    case 'gm/gds-gm/Id'
                        gm_ID=5:0.5:30;
                        gm_gds=look_up(nch,'GM_GDS','GM_ID',gm_ID,'VDS',VDS,'VSB',VSB,'L',L);
                        plot(gm_ID,20*log10(gm_gds)) 
                        xlabel("gm/Id(S/A)"); ylabel("gm/gds(dB)")
                    case 'ft-gm/Id'
                        gm_ID=5:0.5:30;
                        gm_cgg=look_up(nch,'GM_CGG','GM_ID',gm_ID,'VDS',VDS,'VSB',VSB,'L',L);
                        plot(gm_ID,gm_cgg*1e-9)  
                        xlabel("gm/Id(S/A)"); ylabel("fT(GHz)")
                    case 'Cgd/Cgg-gm/Id'
                        gm_ID=5:0.5:30;
                        cgd_cgg=look_up(nch,'CGD_CGG','GM_ID',gm_ID,'VDS',VDS,'VSB',VSB,'L',L);
                        plot(gm_ID,cgd_cgg)   
                        xlabel("gm/Id(S/A)"); ylabel("Cgd/Cgg")
                    case 'Cdd/Cgg-gm/Id'
                        gm_ID=5:0.5:30;
                        cgs_cgg=look_up(nch,'CGS_CGG','GM_ID',gm_ID,'VDS',VDS,'VSB',VSB,'L',L);
                        plot(gm_ID,cgs_cgg)   
                        xlabel("gm/Id(S/A)"); ylabel("Cgs/Cgg")
                end
                legend(LL,'Location','Best')
                grid on                
                
            else   % 绘图 VSB
                VSB=app.FromtoEditField.Value:app.FromtoEditField_2.Value:app.FromtoEditField_3.Value;
                L = app.LEditField.Value;
                VDS = app.LEditField_3.Value;  
                for i=1:length(VSB)
                    LL{i}=['VSB=',num2str(VSB(i)),'V'];
                end       
                switch(app.DropDown.Value)  %% 判断绘图类型 
                    case 'gm/Id-VGS'
                        VGS=0:0.05:1.2;
                        gm_ID=look_up(nch,'GM_ID','VGS',VGS,'VDS',VDS,'VSB',VSB,'L',L);
                        plot(VGS,gm_ID)
                        xlabel("VGS(V)"); ylabel("gm/Id(S/A)")
                    case 'Id/W-gm/Id'
                        gm_ID=5:0.5:30;
                        ID_W=look_up(nch,'ID_W','GM_ID',gm_ID,'VDS',VDS,'VSB',VSB,'L',L);
                        plot(gm_ID,ID_W*1e6)
                        xlabel("gm/Id(S/A)"); ylabel("Id/W(uA/um)")
                    case 'gm/gds-gm/Id'
                        gm_ID=5:0.5:30;
                        gm_gds=look_up(nch,'GM_GDS','GM_ID',gm_ID,'VDS',VDS,'VSB',VSB,'L',L);
                        plot(gm_ID,20*log10(gm_gds)) 
                        xlabel("gm/Id(S/A)"); ylabel("gm/gds(dB)")
                    case 'ft-gm/Id'
                        gm_ID=5:0.5:30;
                        gm_cgg=look_up(nch,'GM_CGG','GM_ID',gm_ID,'VDS',VDS,'VSB',VSB,'L',L);
                        plot(gm_ID,gm_cgg*1e-9)  
                        xlabel("gm/Id(S/A)"); ylabel("fT(GHz)")
                    case 'Cgd/Cgg-gm/Id'
                        gm_ID=5:0.5:30;
                        cgd_cgg=look_up(nch,'CGD_CGG','GM_ID',gm_ID,'VDS',VDS,'VSB',VSB,'L',L);
                        plot(gm_ID,cgd_cgg)   
                        xlabel("gm/Id(S/A)"); ylabel("Cgd/Cgg")
                    case 'Cdd/Cgg-gm/Id'
                        gm_ID=5:0.5:30;
                        cgs_cgg=look_up(nch,'CGS_CGG','GM_ID',gm_ID,'VDS',VDS,'VSB',VSB,'L',L);
                        plot(gm_ID,cgs_cgg)   
                        xlabel("gm/Id(S/A)"); ylabel("Cgs/Cgg")
                end
                legend(LL,'Location','Best')
                grid on    
            end        
            title("NCH",'FontSize',20)
        end

        % Callback function
        function DrowButton_2Pushed(app, event)
            
        end

        % Callback function
        function DrowButton_3Pushed(app, event)
            
        end

        % Button pushed function: loadButton_2
        function loadButton_2Pushed(app, event)
            global pch
            [file_pch,path_pch] = uigetfile('*.mat');
            app.FilenameEditField_2.Value = file_pch;
            load([path_pch file_pch]);  %加载数据
            msgbox("数据加载完毕","通知")
        end

        % Button pushed function: CalculateButton_3
        function CalculateButton_3Pushed(app, event)
            global  pch
            % 变量导入
            L = app.LEditField_4.Value;
            VSB = app.LEditField_5.Value;
            VDS = app.LEditField_6.Value;
            gm_ID = app.gmIDEditField_2.Value;
            
            
            %% 查询过程
            fT=look_up(pch,'GM_CGG','GM_ID',gm_ID,'L',L,'VSB',VSB,'VDS',VDS)/(2*pi);
            VGS=look_upVGS(pch,'GM_ID',gm_ID,'L',L,'VSB',VSB,'VDS',VDS);
            VT=look_up(pch,'VT','VGS',VGS,'L',L,'VSB',VSB,'VDS',VDS);
            gmro=look_up(pch,'GM_GDS','GM_ID',gm_ID,'L',L,'VSB',VSB,'VDS',VDS);
            ID_W=look_up(pch,'ID_W','GM_ID',gm_ID,'L',L,'VSB',VSB,'VDS',VDS);
            CGD_CGG=look_up(pch,'CGD_CGG','GM_ID',gm_ID,'L',L,'VSB',VSB,'VDS',VDS);
            CGS_CGG=look_up(pch,'CGS_CGG','GM_ID',gm_ID,'L',L,'VSB',VSB,'VDS',VDS);


            %% 查询结果显示
            app.OtherParametersTextArea_3.Value = [strcat("VGS=",num2str(VGS,4),"V") ...
                strcat("VGS-VTH=",num2str(VGS-VT,4),"V") ...
                strcat("fT=",num2str(fT/1e9,4),"GHz") ...
                strcat("gm*ro=",num2str(gmro,4)) ...
                strcat("ID/W=",num2str(ID_W*1e6,4),"uA/um") ...
                strcat("CGD/CGG=",num2str(CGD_CGG,4)) ...
                strcat("CGS/CGG=",num2str(CGS_CGG,4))]; % 这里给字符串结果
            
        end

        % Button pushed function: CalculateButton_4
        function CalculateButton_4Pushed(app, event)
            global  pch
            % 变量导入
            L = app.LEditField_4.Value;
            VSB = app.LEditField_5.Value;
            VDS = app.LEditField_6.Value;
            VGS = app.VGSEditField_2.Value;
            
            
            %% 查询过程
            fT=look_up(pch,'GM_CGG','VGS',VGS,'L',L,'VSB',VSB,'VDS',VDS)/(2*pi);
            VT=look_up(pch,'VT','VGS',VGS,'L',L,'VSB',VSB,'VDS',VDS);
            gm_ID=look_up(pch,'GM_ID','VGS',VGS,'L',L,'VSB',VSB,'VDS',VDS);
            gmro=look_up(pch,'GM_GDS','VGS',VGS,'L',L,'VSB',VSB,'VDS',VDS);
            ID_W=look_up(pch,'ID_W','VGS',VGS,'L',L,'VSB',VSB,'VDS',VDS);
            CGD_CGG=look_up(pch,'CGD_CGG','VGS',VGS,'L',L,'VSB',VSB,'VDS',VDS);
            CGS_CGG=look_up(pch,'CGS_CGG','VGS',VGS,'L',L,'VSB',VSB,'VDS',VDS);           


            %% 查询结果显示
            app.OtherParametersTextArea_4.Value = [strcat("gm/ID=",num2str(gm_ID,4),"S/A") ...
                strcat("VGS-VTH=",num2str(VGS-VT,4),"V") ...
                strcat("fT=",num2str(fT/1e9,4),"GHz") ...
                strcat("gm*ro=",num2str(gmro,4)) ...
                strcat("ID/W=",num2str(ID_W*1e6,4),"uA/um") ...
                strcat("CGD/CGG=",num2str(CGD_CGG,4)) ...
                strcat("CGS/CGG=",num2str(CGS_CGG,4))]; % 这里给字符串结果
        end

        % Button pushed function: PlotButton_2
        function PlotButton_2Pushed(app, event)
            %% 绘图 Curves Over Different L/VSB/VDS
            %% PCH
            global pch
            
            if strcmp(app.DropDown_14.Value,'L')   % 绘图 L
                L=app.FromtoEditField_4.Value:app.FromtoEditField_5.Value:app.FromtoEditField_6.Value;
                VSB = app.LEditField_5.Value;
                VDS = app.LEditField_6.Value;
                for i=1:length(L)
                    LL{i}=['L=',num2str(L(i)),'um'];
                end
                switch(app.DropDown_10.Value)  %% 判断绘图类型 
                    case 'gm/Id-VGS'
                        VGS=0:0.05:1.2;
                        gm_ID=look_up(pch,'GM_ID','VGS',VGS,'VDS',VDS,'VSB',VSB,'L',L);
                        plot(VGS,gm_ID)
                        xlabel("VGS(V)"); ylabel("gm/Id(S/A)")
                    case 'Id/W-gm/Id'
                        gm_ID=5:0.5:30;
                        ID_W=look_up(pch,'ID_W','GM_ID',gm_ID,'VDS',VDS,'VSB',VSB,'L',L);
                        plot(gm_ID,ID_W*1e6)
                        xlabel("gm/Id(S/A)"); ylabel("Id/W(uA/um)")
                    case 'gm/gds-gm/Id'
                        gm_ID=5:0.5:30;
                        gm_gds=look_up(pch,'GM_GDS','GM_ID',gm_ID,'VDS',VDS,'VSB',VSB,'L',L);
                        plot(gm_ID,20*log10(gm_gds)) 
                        xlabel("gm/Id(S/A)"); ylabel("gm/gds(dB)")
                    case 'ft-gm/Id'
                        gm_ID=5:0.5:30;
                        gm_cgg=look_up(pch,'GM_CGG','GM_ID',gm_ID,'VDS',VDS,'VSB',VSB,'L',L);
                        plot(gm_ID,gm_cgg*1e-9)  
                        xlabel("gm/Id(S/A)"); ylabel("fT(GHz)")
                    case 'Cgd/Cgg-gm/Id'
                        gm_ID=5:0.5:30;
                        cgd_cgg=look_up(pch,'CGD_CGG','GM_ID',gm_ID,'VDS',VDS,'VSB',VSB,'L',L);
                        plot(gm_ID,cgd_cgg)   
                        xlabel("gm/Id(S/A)"); ylabel("Cgd/Cgg")
                    case 'Cdd/Cgg-gm/Id'
                        gm_ID=5:0.5:30;
                        cgs_cgg=look_up(pch,'CGS_CGG','GM_ID',gm_ID,'VDS',VDS,'VSB',VSB,'L',L);
                        plot(gm_ID,cgs_cgg)   
                        xlabel("gm/Id(S/A)"); ylabel("Cgs/Cgg")
                end
                legend(LL,'Location','Best')
                grid on
            elseif strcmp(app.DropDown_14.Value,'VDS')% 绘图 VDS
                VDS=app.FromtoEditField_4.Value:app.FromtoEditField_5.Value:app.FromtoEditField_6.Value;
                L = app.LEditField_4.Value;
                VSB = app.LEditField_5.Value;  
                for i=1:length(VDS)
                    LL{i}=['VDS=',num2str(VDS(i)),'V'];
                end                
                switch(app.DropDown_10.Value)  %% 判断绘图类型 
                    case 'gm/Id-VGS'
                        VGS=0:0.05:1.2;
                        gm_ID=look_up(pch,'GM_ID','VGS',VGS,'VDS',VDS,'VSB',VSB,'L',L);
                        plot(VGS,gm_ID)
                        xlabel("VGS(V)"); ylabel("gm/Id(S/A)")
                    case 'Id/W-gm/Id'
                        gm_ID=5:0.5:30;
                        ID_W=look_up(pch,'ID_W','GM_ID',gm_ID,'VDS',VDS,'VSB',VSB,'L',L);
                        plot(gm_ID,ID_W*1e6)
                        xlabel("gm/Id(S/A)"); ylabel("Id/W(uA/um)")
                    case 'gm/gds-gm/Id'
                        gm_ID=5:0.5:30;
                        gm_gds=look_up(pch,'GM_GDS','GM_ID',gm_ID,'VDS',VDS,'VSB',VSB,'L',L);
                        plot(gm_ID,20*log10(gm_gds)) 
                        xlabel("gm/Id(S/A)"); ylabel("gm/gds(dB)")
                    case 'ft-gm/Id'
                        gm_ID=5:0.5:30;
                        gm_cgg=look_up(pch,'GM_CGG','GM_ID',gm_ID,'VDS',VDS,'VSB',VSB,'L',L);
                        plot(gm_ID,gm_cgg*1e-9)  
                        xlabel("gm/Id(S/A)"); ylabel("fT(GHz)")
                    case 'Cgd/Cgg-gm/Id'
                        gm_ID=5:0.5:30;
                        cgd_cgg=look_up(pch,'CGD_CGG','GM_ID',gm_ID,'VDS',VDS,'VSB',VSB,'L',L);
                        plot(gm_ID,cgd_cgg)   
                        xlabel("gm/Id(S/A)"); ylabel("Cgd/Cgg")
                    case 'Cdd/Cgg-gm/Id'
                        gm_ID=5:0.5:30;
                        cgs_cgg=look_up(pch,'CGS_CGG','GM_ID',gm_ID,'VDS',VDS,'VSB',VSB,'L',L);
                        plot(gm_ID,cgs_cgg)   
                        xlabel("gm/Id(S/A)"); ylabel("Cgs/Cgg")
                end
                legend(LL,'Location','Best')
                grid on                
                
            else   % 绘图 VSB
                VSB=app.FromtoEditField_4.Value:app.FromtoEditField_5.Value:app.FromtoEditField_6.Value;
                L = app.LEditField_4.Value;
                VDS = app.LEditField_6.Value;  
                for i=1:length(VSB)
                    LL{i}=['VSB=',num2str(VSB(i)),'V'];
                end       
                switch(app.DropDown_10.Value)  %% 判断绘图类型 
                    case 'gm/Id-VGS'
                        VGS=0:0.05:1.2;
                        gm_ID=look_up(pch,'GM_ID','VGS',VGS,'VDS',VDS,'VSB',VSB,'L',L);
                        plot(VGS,gm_ID)
                        xlabel("VGS(V)"); ylabel("gm/Id(S/A)")
                    case 'Id/W-gm/Id'
                        gm_ID=5:0.5:30;
                        ID_W=look_up(pch,'ID_W','GM_ID',gm_ID,'VDS',VDS,'VSB',VSB,'L',L);
                        plot(gm_ID,ID_W*1e6)
                        xlabel("gm/Id(S/A)"); ylabel("Id/W(uA/um)")
                    case 'gm/gds-gm/Id'
                        gm_ID=5:0.5:30;
                        gm_gds=look_up(pch,'GM_GDS','GM_ID',gm_ID,'VDS',VDS,'VSB',VSB,'L',L);
                        plot(gm_ID,20*log10(gm_gds)) 
                        xlabel("gm/Id(S/A)"); ylabel("gm/gds(dB)")
                    case 'ft-gm/Id'
                        gm_ID=5:0.5:30;
                        gm_cgg=look_up(pch,'GM_CGG','GM_ID',gm_ID,'VDS',VDS,'VSB',VSB,'L',L);
                        plot(gm_ID,gm_cgg*1e-9)  
                        xlabel("gm/Id(S/A)"); ylabel("fT(GHz)")
                    case 'Cgd/Cgg-gm/Id'
                        gm_ID=5:0.5:30;
                        cgd_cgg=look_up(pch,'CGD_CGG','GM_ID',gm_ID,'VDS',VDS,'VSB',VSB,'L',L);
                        plot(gm_ID,cgd_cgg)   
                        xlabel("gm/Id(S/A)"); ylabel("Cgd/Cgg")
                    case 'Cdd/Cgg-gm/Id'
                        gm_ID=5:0.5:30;
                        cgs_cgg=look_up(pch,'CGS_CGG','GM_ID',gm_ID,'VDS',VDS,'VSB',VSB,'L',L);
                        plot(gm_ID,cgs_cgg)   
                        xlabel("gm/Id(S/A)"); ylabel("Cgs/Cgg")
                end
                legend(LL,'Location','Best')
                grid on
            end
            title("PCH",'FontSize',20)
        end

        % Callback function
        function DrowButton_5Pushed(app, event)
            
        end

        % Callback function
        function DrowButton_6Pushed(app, event)
            
        end

        % Button pushed function: CalculateButton_5
        function CalculateButton_5Pushed(app, event)
            global nch
            
            % 变量导入
            L = app.LEditField.Value;
            VSB = app.LEditField_2.Value;
            VDS = app.LEditField_3.Value;
            
            gm_Id = app.gmIdEditField.Value;
            
            if app.IDButton.Value  % 判断给的GM还是ID

                Id = app.IDuAorgmuSEditField.Value*1e-6;
                gm = gm_Id*Id;
            else
                gm = app.IDuAorgmuSEditField.Value*1e-6;
                Id = gm/gm_Id;
            end
            %% 查询过程 W L Cgg Cgs Cgd Cgb Cdd
            W=Id/look_up(nch,'ID_W','GM_ID',gm_Id,'L',L,'VSB',VSB,'VDS',VDS);
            Cgg=W*look_up(nch,'CGG_W','GM_ID',gm_Id,'L',L,'VSB',VSB,'VDS',VDS);
            Cgd=W*look_up(nch,'CGD_W','GM_ID',gm_Id,'L',L,'VSB',VSB,'VDS',VDS);
            Cgs=W*look_up(nch,'CGS_W','GM_ID',gm_Id,'L',L,'VSB',VSB,'VDS',VDS);
            Cgb=W*look_up(nch,'CGB_W','GM_ID',gm_Id,'L',L,'VSB',VSB,'VDS',VDS);
            %Cdd=W*look_up(nch,'CDD_W','GM_ID',gm_Id,'L',L,'VSB',VSB,'VDS',VDS);
            %Cdd is not very accurate. 
            ro=look_up(nch,'GM_GDS','GM_ID',gm_Id,'L',L,'VSB',VSB,'VDS',VDS)/(gm);
            
            %% 查询结果
            
            app.ParametersTextArea.Value = [strcat("gm=",num2str(gm*1e6,4),"uS") ...
                strcat("Id=",num2str(Id*1e6,4),"uA") ...                
                strcat("ro=",num2str(ro,4),"Ohms") ...
                strcat('W/L=',num2str(W,4),'um/',num2str(L),'um') ...
                strcat("Cgg=",num2str(Cgg*1e15,4),"fF") ...
                strcat("Cgd=",num2str(Cgd*1e15,4),"fF") ...
                strcat("Cgs=",num2str(Cgs*1e15,4),"fF") ...
                strcat("Cgb=",num2str(Cgb*1e15,4),"fF")  ]; % 这里给字符串结果


        end

        % Button pushed function: CalculateButton_6
        function CalculateButton_6Pushed(app, event)
            global pch
            
            % 变量导入
            L = app.LEditField_4.Value;
            VSB = app.LEditField_5.Value;
            VDS = app.LEditField_6.Value;

            
            gm_Id = app.gmIdEditField_2.Value;
            
            if app.IDButton_2.Value  % 判断给的GM还是ID
                Id = app.IDuAorgmuSEditField_2.Value*1e-6;
                gm = gm_Id*Id;
            else
                gm = app.IDuAorgmuSEditField_2.Value*1e-6;
                Id = gm/gm_Id;
            end
            %% 查询过程
            W=Id/look_up(pch,'ID_W','GM_ID',gm_Id,'L',L,'VSB',VSB,'VDS',VDS);
            Cgg=W*look_up(pch,'CGG_W','GM_ID',gm_Id,'L',L,'VSB',VSB,'VDS',VDS);
            Cgd=W*look_up(pch,'CGD_W','GM_ID',gm_Id,'L',L,'VSB',VSB,'VDS',VDS);
            Cgs=W*look_up(pch,'CGS_W','GM_ID',gm_Id,'L',L,'VSB',VSB,'VDS',VDS);
            Cgb=W*look_up(pch,'CGB_W','GM_ID',gm_Id,'L',L,'VSB',VSB,'VDS',VDS);
            %Cdd=W*look_up(pch,'CDD_W','GM_ID',gm_Id,'L',L,'VSB',VSB,'VDS',VDS);
            %Because Cdd is not very accurate here. 
            ro=look_up(pch,'GM_GDS','GM_ID',gm_Id,'L',L,'VSB',VSB,'VDS',VDS)/(gm);            
            
            
            %% 查询结果
            
            app.ParametersTextArea_2.Value = [strcat("gm=",num2str(gm*1e6,4),"uS") ...
                strcat("Id=",num2str(Id*1e6,4),"uA") ...                
                strcat("ro=",num2str(ro,4),"Ohms") ...
                strcat('W/L=',num2str(W,4),'um/',num2str(L),'um') ...
                strcat("Cgg=",num2str(Cgg*1e15,4),"fF") ...
                strcat("Cgd=",num2str(Cgd*1e15,4),"fF") ...
                strcat("Cgs=",num2str(Cgs*1e15,4),"fF") ...
                strcat("Cgb=",num2str(Cgb*1e15,4),"fF")  ]; % 这里给字符串结果
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            % RESET NCH
            
            app.FilenameEditField.Value = '';
            app.LEditField.Value = 0;
            app.LEditField_2.Value = 0;
            app.LEditField_3.Value = 0;
            app.gmIDEditField.Value = 0;
            app.VGSEditField.Value = 0;
            app.OtherParametersTextArea.Value = '';
            app.OtherParametersTextArea_2.Value = '';
            app.DropDown_13.Value = 'L';
            app.DropDown.Value = 'gm/Id-VGS';
            
            % RESET PCH
            
            app.FilenameEditField_2.Value = '';
            app.LEditField_4.Value = 0;
            app.LEditField_5.Value = 0;
            app.LEditField_6.Value = 0;
            app.gmIDEditField_2.Value = 0;
            app.OtherParametersTextArea_3.Value = '';
            app.VGSEditField_2.Value = 0;
            app.OtherParametersTextArea_4.Value = '';
            app.DropDown_14.Value = 'L';
            app.DropDown_10.Value = 'gm/Id-VGS';
            
            % RESET NCH2
            
            app.gmIdEditField.Value = 0;
            app.IDuAorgmuSEditField.Value = 0;
            app.IDButton.Value = true;
            app.gmButton.Value = false;
            app.ParametersTextArea.Value = '';
            % RESET PCH2
            app.gmIdEditField_2.Value = 0;
            app.IDuAorgmuSEditField_2.Value = 0;
            app.IDButton_2.Value = true;
            app.gmButton_2.Value = false;
            app.ParametersTextArea_2.Value = '';
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NCHPanel = uipanel(app.UIFigure);
            app.NCHPanel.Title = 'NCH';
            app.FilenameLabel = uilabel(app.NCHPanel);
            app.FilenameLabel.Text = 'Filename:';
            app.FilenameEditField = uieditfield(app.NCHPanel, 'text');
            app.FilenameEditField.Editable = 'off';
            app.loadButton = uibutton(app.NCHPanel, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadButton.Text = 'load';
            app.LEditFieldLabel = uilabel(app.NCHPanel);
            app.LEditFieldLabel.Text = 'L=';
            app.LEditField = uieditfield(app.NCHPanel, 'numeric');
            app.umLabel = uilabel(app.NCHPanel);
            app.umLabel.Text = 'um';
            app.LEditFieldLabel_2 = uilabel(app.NCHPanel);
            app.LEditFieldLabel_2.Text = 'VSB=';
            app.LEditField_2 = uieditfield(app.NCHPanel, 'numeric');
            app.VLabel = uilabel(app.NCHPanel);
            app.VLabel.Text = 'V';
            app.LEditFieldLabel_3 = uilabel(app.NCHPanel);
            app.LEditFieldLabel_3.Text = 'VDS=';
            app.LEditField_3 = uieditfield(app.NCHPanel, 'numeric');
            app.VLabel_2 = uilabel(app.NCHPanel);
            app.VLabel_2.Text = 'V';
            app.gmIDEditFieldLabel = uilabel(app.NCHPanel);
            app.gmIDEditFieldLabel.Text = 'gm/ID';
            app.gmIDEditField = uieditfield(app.NCHPanel, 'numeric');
            app.SALabel = uilabel(app.NCHPanel);
            app.SALabel.Text = 'S/A';
            app.CalculateButton = uibutton(app.NCHPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.OtherParametersTextAreaLabel = uilabel(app.NCHPanel);
            app.OtherParametersTextAreaLabel.Text = 'Other Parameters';
            app.OtherParametersTextArea = uitextarea(app.NCHPanel);
            app.VLabel_3 = uilabel(app.NCHPanel);
            app.VLabel_3.Text = 'V';
            app.VGSEditFieldLabel = uilabel(app.NCHPanel);
            app.VGSEditFieldLabel.Text = 'VGS';
            app.VGSEditField = uieditfield(app.NCHPanel, 'numeric');
            app.CalculateButton_2 = uibutton(app.NCHPanel, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = 'Calculate';
            app.OtherParametersTextArea_2Label = uilabel(app.NCHPanel);
            app.OtherParametersTextArea_2Label.Text = 'Other Parameters';
            app.OtherParametersTextArea_2 = uitextarea(app.NCHPanel);
            app.CurvesUnderDifferentLVDSVSBLabel = uilabel(app.NCHPanel);
            app.CurvesUnderDifferentLVDSVSBLabel.Text = 'Curves Under Different L/VDS/VSB';
            app.PlotButton = uibutton(app.NCHPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.DropDown = uidropdown(app.NCHPanel);
            app.DropDown.Items = {'gm/Id-VGS', 'Id/W-gm/Id', 'gm/gds-gm/Id', 'ft-gm/Id', 'Cgd/Cgg-gm/Id', 'Cdd/Cgg-gm/Id'};
            app.DropDown.Value = 'gm/Id-VGS';
            app.DropDown_13 = uidropdown(app.NCHPanel);
            app.DropDown_13.Items = {'L', 'VDS', 'VSB'};
            app.DropDown_13.Value = 'L';
            app.Label = uilabel(app.NCHPanel);
            app.Label.Text = '-------------------------------------------------------------------------------------------------------------------------';
            app.Label_3 = uilabel(app.NCHPanel);
            app.Label_3.Text = '-------------------------------------------------------------------------------------------------------------------------';
            app.Label_4 = uilabel(app.NCHPanel);
            app.Label_4.Text = '-------------------------------------------------------------------------------------------------------------------------';
            app.FromtoEditField = uieditfield(app.NCHPanel, 'numeric');
            app.FromtoEditField_2 = uieditfield(app.NCHPanel, 'numeric');
            app.FromtoEditField_3 = uieditfield(app.NCHPanel, 'numeric');
            app.Label_8 = uilabel(app.NCHPanel);
            app.Label_8.Text = ':';
            app.Label_9 = uilabel(app.NCHPanel);
            app.Label_9.Text = ':';
            app.PCHPanel = uipanel(app.UIFigure);
            app.PCHPanel.Title = 'PCH';
            app.FilenameLabel_2 = uilabel(app.PCHPanel);
            app.FilenameLabel_2.Text = 'Filename:';
            app.FilenameEditField_2 = uieditfield(app.PCHPanel, 'text');
            app.FilenameEditField_2.Editable = 'off';
            app.LEditField_4Label = uilabel(app.PCHPanel);
            app.LEditField_4Label.Text = 'L=';
            app.LEditField_4 = uieditfield(app.PCHPanel, 'numeric');
            app.umLabel_2 = uilabel(app.PCHPanel);
            app.umLabel_2.Text = 'um';
            app.LEditFieldLabel_4 = uilabel(app.PCHPanel);
            app.LEditFieldLabel_4.Text = 'VSB=';
            app.LEditField_5 = uieditfield(app.PCHPanel, 'numeric');
            app.VLabel_4 = uilabel(app.PCHPanel);
            app.VLabel_4.Text = 'V';
            app.LEditFieldLabel_5 = uilabel(app.PCHPanel);
            app.LEditFieldLabel_5.Text = 'VDS=';
            app.LEditField_6 = uieditfield(app.PCHPanel, 'numeric');
            app.VLabel_5 = uilabel(app.PCHPanel);
            app.VLabel_5.Text = 'V';
            app.gmIDEditField_2Label = uilabel(app.PCHPanel);
            app.gmIDEditField_2Label.Text = 'gm/ID';
            app.gmIDEditField_2 = uieditfield(app.PCHPanel, 'numeric');
            app.SALabel_2 = uilabel(app.PCHPanel);
            app.SALabel_2.Text = 'S/A';
            app.OtherParametersTextArea_3Label = uilabel(app.PCHPanel);
            app.OtherParametersTextArea_3Label.Text = 'Other Parameters';
            app.OtherParametersTextArea_3 = uitextarea(app.PCHPanel);
            app.VLabel_6 = uilabel(app.PCHPanel);
            app.VLabel_6.Text = 'V';
            app.VGSEditField_2Label = uilabel(app.PCHPanel);
            app.VGSEditField_2Label.Text = 'VGS';
            app.VGSEditField_2 = uieditfield(app.PCHPanel, 'numeric');
            app.OtherParametersTextArea_4Label = uilabel(app.PCHPanel);
            app.OtherParametersTextArea_4Label.Text = 'Other Parameters';
            app.OtherParametersTextArea_4 = uitextarea(app.PCHPanel);
            app.CurvesUnderDifferentLVDSVSBLabel_2 = uilabel(app.PCHPanel);
            app.CurvesUnderDifferentLVDSVSBLabel_2.Text = 'Curves Under Different L/VDS/VSB';
            app.DropDown_14 = uidropdown(app.PCHPanel);
            app.DropDown_14.Items = {'L', 'VDS', 'VSB'};
            app.DropDown_14.Value = 'L';
            app.loadButton_2 = uibutton(app.PCHPanel, 'push');
            app.loadButton_2.ButtonPushedFcn = createCallbackFcn(app, @loadButton_2Pushed, true);
            app.loadButton_2.Text = 'load';
            app.CalculateButton_3 = uibutton(app.PCHPanel, 'push');
            app.CalculateButton_3.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_3Pushed, true);
            app.CalculateButton_3.Text = 'Calculate';
            app.CalculateButton_4 = uibutton(app.PCHPanel, 'push');
            app.CalculateButton_4.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_4Pushed, true);
            app.CalculateButton_4.Text = 'Calculate';
            app.DropDown_10 = uidropdown(app.PCHPanel);
            app.DropDown_10.Items = {'gm/Id-VGS', 'Id/W-gm/Id', 'gm/gds-gm/Id', 'ft-gm/Id', 'Cgd/Cgg-gm/Id', 'Cdd/Cgg-gm/Id'};
            app.DropDown_10.Value = 'gm/Id-VGS';
            app.PlotButton_2 = uibutton(app.PCHPanel, 'push');
            app.PlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_2Pushed, true);
            app.PlotButton_2.Text = 'Plot';
            app.FromtoEditField_4 = uieditfield(app.PCHPanel, 'numeric');
            app.FromtoEditField_5 = uieditfield(app.PCHPanel, 'numeric');
            app.FromtoEditField_6 = uieditfield(app.PCHPanel, 'numeric');
            app.Label_10 = uilabel(app.PCHPanel);
            app.Label_10.Text = ':';
            app.Label_11 = uilabel(app.PCHPanel);
            app.Label_11.Text = ':';
            app.GmidHelperLabel = uilabel(app.UIFigure);
            app.GmidHelperLabel.Text = 'Gmid Helper';
            app.NCHSizePanel = uipanel(app.UIFigure);
            app.NCHSizePanel.Title = 'NCH Size';
            app.IDuAorgmuSEditFieldLabel = uilabel(app.NCHSizePanel);
            app.IDuAorgmuSEditFieldLabel.Text = 'ID(uA) or gm(uS)';
            app.IDuAorgmuSEditField = uieditfield(app.NCHSizePanel, 'numeric');
            app.ButtonGroup = uibuttongroup(app.NCHSizePanel);
            app.IDButton = uiradiobutton(app.ButtonGroup);
            app.IDButton.Text = 'ID';
            app.IDButton.Value = true;
            app.gmButton = uiradiobutton(app.ButtonGroup);
            app.gmButton.Text = 'gm';
            app.gmIdEditFieldLabel = uilabel(app.NCHSizePanel);
            app.gmIdEditFieldLabel.Text = 'gm/Id';
            app.gmIdEditField = uieditfield(app.NCHSizePanel, 'numeric');
            app.SALabel_3 = uilabel(app.NCHSizePanel);
            app.SALabel_3.Text = 'S/A';
            app.CalculateButton_5 = uibutton(app.NCHSizePanel, 'push');
            app.CalculateButton_5.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_5Pushed, true);
            app.CalculateButton_5.Text = 'Calculate';
            app.ParametersTextAreaLabel = uilabel(app.NCHSizePanel);
            app.ParametersTextAreaLabel.Text = 'Parameters';
            app.ParametersTextArea = uitextarea(app.NCHSizePanel);
            app.PCHSizePanel = uipanel(app.UIFigure);
            app.PCHSizePanel.Title = 'PCH Size';
            app.IDuAorgmuSEditField_2Label = uilabel(app.PCHSizePanel);
            app.IDuAorgmuSEditField_2Label.Text = 'ID(uA) or gm(uS)';
            app.IDuAorgmuSEditField_2 = uieditfield(app.PCHSizePanel, 'numeric');
            app.ButtonGroup_2 = uibuttongroup(app.PCHSizePanel);
            app.IDButton_2 = uiradiobutton(app.ButtonGroup_2);
            app.IDButton_2.Text = 'ID';
            app.IDButton_2.Value = true;
            app.gmButton_2 = uiradiobutton(app.ButtonGroup_2);
            app.gmButton_2.Text = 'gm';
            app.gmIdEditField_2Label = uilabel(app.PCHSizePanel);
            app.gmIdEditField_2Label.Text = 'gm/Id';
            app.gmIdEditField_2 = uieditfield(app.PCHSizePanel, 'numeric');
            app.SALabel_4 = uilabel(app.PCHSizePanel);
            app.SALabel_4.Text = 'S/A';
            app.ParametersTextArea_2Label = uilabel(app.PCHSizePanel);
            app.ParametersTextArea_2Label.Text = 'Parameters';
            app.ParametersTextArea_2 = uitextarea(app.PCHSizePanel);
            app.CalculateButton_6 = uibutton(app.PCHSizePanel, 'push');
            app.CalculateButton_6.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_6Pushed, true);
            app.CalculateButton_6.Text = 'Calculate';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '-------------------------------------------------------------------------------------------------------------------------';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '-------------------------------------------------------------------------------------------------------------------------';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '-------------------------------------------------------------------------------------------------------------------------';
            app.byRixianSEULabel = uilabel(app.UIFigure);
            app.byRixianSEULabel.Text = 'by Rixian (SEU)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GmidHelper

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end