classdef my_gmoverid < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        gmidEditField           matlab.ui.control.NumericEditField
        gmidEditFieldLabel      matlab.ui.control.Label
        CulculateButton         matlab.ui.control.Button
        VLabel_2                matlab.ui.control.Label
        VdsEditField            matlab.ui.control.NumericEditField
        VdsEditFieldLabel       matlab.ui.control.Label
        VLabel                  matlab.ui.control.Label
        VgsEditField            matlab.ui.control.NumericEditField
        VgsEditFieldLabel       matlab.ui.control.Label
        umLabel                 matlab.ui.control.Label
        LEditField              matlab.ui.control.NumericEditField
        LEditFieldLabel         matlab.ui.control.Label
        LoadButton              matlab.ui.control.Button
        FileNameEditField       matlab.ui.control.EditField
        FileNameEditFieldLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            global nch;
            file_name = app.FileNameEditField.Value;
            load(file_name);
            msgbox("数据加载完毕","通知")
        end

        % Button pushed function: CulculateButton
        function CulculateButtonPushed(app, event)
            global nch;
            nch_l = app.LEditField.Value;
            nch_vgs = app.VgsEditField.Value;
            nch_vds = app.VdsEditField.Value;
            nch_gm = look_up(nch, 'GM', 'VDS', nch_vds, 'VGS', nch_vgs,'L',nch_l);
            nch_id = look_up(nch, 'ID', 'VDS', nch_vds, 'VGS', nch_vgs,'L',nch_l);
            nch_gmoverid = nch_gm/nch_id;
            app.gmidEditField.Value=nch_gmoverid;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.UIFigure, 'text');
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.LEditFieldLabel = uilabel(app.UIFigure);
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.UIFigure, 'numeric');
            app.umLabel = uilabel(app.UIFigure);
            app.umLabel.Text = 'um';
            app.VgsEditFieldLabel = uilabel(app.UIFigure);
            app.VgsEditFieldLabel.Text = 'Vgs';
            app.VgsEditField = uieditfield(app.UIFigure, 'numeric');
            app.VLabel = uilabel(app.UIFigure);
            app.VLabel.Text = 'V';
            app.VdsEditFieldLabel = uilabel(app.UIFigure);
            app.VdsEditFieldLabel.Text = 'Vds';
            app.VdsEditField = uieditfield(app.UIFigure, 'numeric');
            app.VLabel_2 = uilabel(app.UIFigure);
            app.VLabel_2.Text = 'V';
            app.CulculateButton = uibutton(app.UIFigure, 'push');
            app.CulculateButton.ButtonPushedFcn = createCallbackFcn(app, @CulculateButtonPushed, true);
            app.CulculateButton.Text = 'Culculate';
            app.gmidEditFieldLabel = uilabel(app.UIFigure);
            app.gmidEditFieldLabel.Text = 'gm/id';
            app.gmidEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = my_gmoverid

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end